classdef CCPStackingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OKButton                    matlab.ui.control.Button
        CancelGUI                   matlab.ui.control.Button
        CCPcalculatePanel           matlab.ui.container.Panel
        RunButtonCCP                matlab.ui.control.Button
        SmoothingEditField          matlab.ui.control.NumericEditField
        SmoothingkmLabel            matlab.ui.control.Label
        GridPanel                   matlab.ui.container.Panel
        PlotparametersPanel         matlab.ui.container.Panel
        SaveButton                  matlab.ui.control.Button
        PlotButton                  matlab.ui.control.Button
        Y2EditField                 matlab.ui.control.NumericEditField
        Y2Label                     matlab.ui.control.Label
        X2EditField                 matlab.ui.control.NumericEditField
        X2Label                     matlab.ui.control.Label
        Y1EditField                 matlab.ui.control.NumericEditField
        Y1Label                     matlab.ui.control.Label
        X1EditField                 matlab.ui.control.NumericEditField
        X1Label                     matlab.ui.control.Label
        CreategridPanel             matlab.ui.container.Panel
        VelocityModelDropDown       matlab.ui.control.DropDown
        VelocityModelDropDownLabel  matlab.ui.control.Label
        CreateButton                matlab.ui.control.Button
        dzkmEditField               matlab.ui.control.NumericEditField
        dzLabel                     matlab.ui.control.Label
        zmaxkmEditField             matlab.ui.control.NumericEditField
        zmaxkmEditFieldLabel        matlab.ui.control.Label
        dykmEditField               matlab.ui.control.NumericEditField
        dyLabel                     matlab.ui.control.Label
        dxEditField                 matlab.ui.control.NumericEditField
        dxkmLabel                   matlab.ui.control.Label
        StatusEditField             matlab.ui.control.EditField
        StatusEditFieldLabel        matlab.ui.control.Label
        CCPStackingPanelLabel       matlab.ui.control.Label
        CCPImageMap                 matlab.ui.control.UIAxes
    end

    properties (Access = public)
        mainApp
        DataStruct          % 用于存放地震数据
        ccpResult       % 存放叠加后的结果
        gridStruct
        plotFlag=false
        cmap
    end
    
    methods (Access = private)
        
        function plotStationsInApp(app, gridStruct,plotFlag)
            % 清空 Panel 并删除所有子对象
            delete(allchild(app.GridPanel));
            
            % 提取台站坐标
            stationX = gridStruct.rxInOriginalCoord(:, 1);
            stationY = gridStruct.ryInOriginalCoord(:, 2);
%             stationX = gridStruct.stationX;
%             stationY = gridStruct.stationY;
            
            % 提取网格点坐标
            XX = gridStruct.X;
            YY = gridStruct.Y;
%             XX = gridStruct.XInOriginalCoord;
%             YY = gridStruct.YInOriginalCoord;
            
            %------------------- 2. 清空 Panel 并创建坐标轴 -------------------%
            gax = axes('Parent', app.GridPanel, ...
                'Units', 'normalized', ...
                'Position', [0.05 0.12 0.95 0.80]); % 调整坐标轴位置，留出边距
            hold(gax, 'on'); % 统一使用坐标轴句柄
            
            %------------------- 3. 绘制台站、网格点和投影点 -------------------%
            % 绘制台站（红色三角，较大尺寸）
            scatter(gax, stationX, stationY, 40, 'r^', 'filled', ...
                'LineWidth', 1.5, ...
                'MarkerEdgeColor', 'r', ...
                'DisplayName', '台站位置');
            
            % 绘制网格点（灰色点，适当缩小尺寸）
            scatter(gax, XX, YY, 80, [0.5 0.5 0.5], '.', ...
                'filled', 'MarkerEdgeColor', 'k', ...
                'DisplayName', '网格点');
            
            %------------------- 4. 添加标签和图例 -------------------%
            xlabel(gax, '东向距离 (km)', 'FontSize', 12, 'FontWeight', 'bold');
            ylabel(gax, '北向距离 (km)', 'FontSize', 12, 'FontWeight', 'bold');
            title(gax, '台站与网格分布图', 'FontSize', 14, 'FontWeight', 'bold');
            
            % 添加图例
            % legend(gax, 'show', 'Location', 'best', 'FontSize', 10);
            
            % 启用网格线
            box(gax,"on");
            grid(gax, 'on');
            grid(gax, 'minor'); % 添加次要网格线
            
            % 调整坐标轴比例，确保等比例显示
            axis(gax, 'equal');
            
            % 设置坐标轴范围，留出一定的边距
            xMargin = 0.1 * (max(XX(:)) - min(XX(:))); % 10% 的边距
            yMargin = 0.1 * (max(YY(:)) - min(YY(:))); % 10% 的边距
            xlim(gax, [min(XX(:)) - xMargin, max(XX(:)) + xMargin]);
            ylim(gax, [min(YY(:)) - yMargin, max(YY(:)) + yMargin]);
            
            % 设置坐标轴字体大小
            set(gax, 'FontSize', 10);
            
            % 设置背景颜色为白色
            set(gax, 'Color', 'w');
            set(app.GridPanel, 'BackgroundColor', 'w');

            if plotFlag == 1 
                coord1 = [app.X1EditField.Value,app.Y1EditField.Value];
                coord2 = [app.X2EditField.Value,app.Y2EditField.Value];
    
                plot(gax,[coord1(1),coord2(1)],[coord1(2),coord2(2)],'b-o','LineWidth',2)
                % plot(gax,coord1, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');  % 红色填充圆点
                % plot(gax,coord1, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');

                text(gax,coord1(1),coord1(2),'A','FontSize',20,'HorizontalAlignment','right')
                text(gax,coord2(1),coord2(2),'B','FontSize',20,'HorizontalAlignment','left')
            end

            grid(gax, 'on');
            hold(gax, 'off');
        end

    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cmap = load('roma.mat');
        end

        % Callback function
        function CancelButtonPushed(app, event)
            answer = questdlg('是否退出？', '', 'YES', 'NO', 'NO');
        
            % 根据用户选择执行操作
            switch answer
                case 'YES'
                    delete(app.UIFigure); % 删除主窗口以退出
                case 'NO'
                    return; % 取消关闭操作
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            if isempty(app.DataStruct)
                uialert(app.UIFigure, '尚未读取数据！', '错误','Icon','error');
                return;
            end

            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','CCP叠加', ...
                    'Message','创建网格及速度模型中，请稍候...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');
            % create grid
            app.gridStruct = createGrid(app.DataStruct, app.dxEditField.Value, app.dykmEditField.Value, app.dzkmEditField.Value, app.zmaxkmEditField.Value);

            % velocity model
            ModelType = app.VelocityModelDropDown.Value;

            if strcmp(ModelType,'Default')
                ModelType = '3D';
            end

            npts = 5;
            app.gridStruct = getVelocityModel(ModelType,app.gridStruct,npts);
%             if strcmp(ModelType,'3D')
%                 depindx = find(app.gridStruct.z == 50);          
%                 Vs = app.gridStruct.VS(:,:,depindx);
%             end

            plotStationsInApp(app,app.gridStruct,app.plotFlag);
            app.StatusEditField.Value = '网格创建完成';

            close(msg);


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            smoothLength = fix(app.SmoothingEditField.Value/app.dxEditField.Value) + 1;

            if rem(smoothLength,2)==0
                smoothLength = smoothLength +1;
            end

            app.plotFlag = true;
            plotStationsInApp(app,app.gridStruct,app.plotFlag);

            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','CCP结果', ...
                    'Message','结果展示，请稍候...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');
            
            X = app.ccpResult.X;
            Y = app.ccpResult.Y;
            Z = app.ccpResult.Z;
            [~,~,nz] = size(X);
            dimg_smooth = [];
            
            for n = 1:size(app.ccpResult.img,4)
                dimg_smooth(:,:,:,n) = smooth3(squeeze(app.ccpResult.img(:,:,:,n)),'box',smoothLength);
            end

            V_smooth = mean(dimg_smooth,4);

            % plot
            Fi = scatteredInterpolant(X(:),Y(:),Z(:),V_smooth(:));

            x1 = app.X1EditField.Value;
            x2 = app.X2EditField.Value;
            y1 = app.Y1EditField.Value;
            y2 = app.Y2EditField.Value;

            depth = squeeze(Z(1,1,:));

            xi = linspace(x1,x2,50);
            yi = linspace(y1,y2,50);

            xdist = linspace(0,sqrt((x1-x2)^2 + (y1-y2)^2),50);

            xpro = repmat(xi,nz,1);
            ypro = repmat(yi,nz,1);
            zpro = repmat(depth,1,50);

            Vpro = Fi(xpro,ypro,zpro);

            %%
            cla(app.CCPImageMap,"reset");  % 清空并重置坐标轴
            % axes(app.CCPImageMap);
            % hold(app.CCPImageMap, 'on');    % 允许叠加绘图（如后续需要）
            imagesc(app.CCPImageMap, xdist, depth, Vpro); % 绘制图像
            
            % 设置坐标轴属性
            axis(app.CCPImageMap, 'equal'); % 等比例坐标轴
            xlabel(app.CCPImageMap, 'Distance (km)');
            ylabel(app.CCPImageMap, 'Depth (km)');
            xlim(app.CCPImageMap, [0, max(xdist)]);
            ylim(app.CCPImageMap, [0, 100]);
            text(app.CCPImageMap,0,-5,'A','FontSize',14)
            text(app.CCPImageMap,max(xdist)-5,-5,'B','FontSize',14)
                        
            % 设置颜色范围（确保使用正确的数据计算）
            cmax = rms(Vpro(:)); % 假设需基于Vpro而非V_smooth
            caxis(app.CCPImageMap, [-3*cmax, 3*cmax]); % 显式指定坐标轴
            % caxis(app.CCPImageMap, [-1 1]);
            
            % 可选：设置颜色图
%             colormap(app.CCPImageMap, 'jet'); % 例如使用jet颜色图
            colormap(app.CCPImageMap,flipud(app.cmap.roma));
            colorbar(app.CCPImageMap)
            app.StatusEditField.Value = 'CCP图像展示';

            close(msg);
            uialert(app.UIFigure, 'CCP图像绘制完成！', '提示');

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename,path,index] = uiputfile({...
                '*.png','PNG file'; ...
                '*.jpg','JPG file'},'请选择保存的文件名');
            if isequal(filename,0)
                disp('取消')
            else
                formattype = {'-dpng','-djpg'};
                f = figure('Visible','off');
                ax = axes(f);
                copyobj(app.CCPImageMap.Parent,ax);
                print(f,[path,filename],formattype{index});
            end
        end

        % Button pushed function: CancelGUI
        function CancelGUIPushed(app, event)
            answer = questdlg('是否退出？', '', 'YES', 'NO', 'NO');
        
            % 根据用户选择执行操作
            switch answer
                case 'YES'
                    delete(app.UIFigure); % 删除主窗口以退出
                case 'NO'
                    return; % 取消关闭操作
            end
        end

        % Button pushed function: RunButtonCCP
        function RunButtonCCPPushed(app, event)
            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','CCP结果', ...
                    'Message','CCP计算中，请稍候...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');

            eventList = getEvents(app.DataStruct);
            eventid = {eventList.evid};

            dimg = [];    % 存储最CCP结果
            count = [];
            nMigratedEvents = 1;   % 用于计数成功完成成像的事件数
            
            % 遍历所有符合筛选条件的事件
            for iEvent = 1:length(eventid)
                evid = eventid{iEvent}; 
                % 提取当前事件对应的地震记录子集（Common Event Gather）
                gather = getCommonEventGather(app.DataStruct, evid);
                % 如果该事件的有效台站数小于 50，则跳过，以保证成像质量
                if length(gather) < 50
                    continue
                end

                msg.Value = (iEvent-1)/length(eventid);
                msg.Message = sprintf('正在处理：第 (%d/%d) 个地震', iEvent, length(eventid));
                drawnow;  % 强制界面刷新
            
                % 调用 CCPCommonEventGather 进行共转换点叠加成像
                Result = CCPCommonEventGather(gather, app.gridStruct);
                dimg(:,:,:,nMigratedEvents) = Result.img;
                count(:,:,:,nMigratedEvents) = Result.count;
                
                nMigratedEvents = nMigratedEvents + 1;
            end

            app.ccpResult.img = dimg;
            app.ccpResult.X = Result.X;
            app.ccpResult.Y = Result.Y;
            app.ccpResult.Z = Result.Z;

            msg.Value = 1;
            msg.Message = '所有数据处理完成！';
            drawnow;

            close(msg);
            uialert(app.UIFigure, 'CCP计算完成！', '提示');
            app.StatusEditField.Value = 'CCP处理完成';
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % 将其赋值给主 App
            app.mainApp.ccpStruct = app.ccpResult;
            app.mainApp.CCPStackingStatus = 'OK';
            % 关闭窗口
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CCPImageMap = uiaxes(app.UIFigure);
            title(app.CCPImageMap, 'CCP image')
            xlabel(app.CCPImageMap, 'Distance (km)')
            ylabel(app.CCPImageMap, 'Depth (km)')
            zlabel(app.CCPImageMap, 'Z')
            app.CCPStackingPanelLabel = uilabel(app.UIFigure);
            app.CCPStackingPanelLabel.Text = 'CCP Stacking Panel';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.CreategridPanel = uipanel(app.UIFigure);
            app.CreategridPanel.Title = 'Create grid';
            app.dxkmLabel = uilabel(app.CreategridPanel);
            app.dxkmLabel.Text = 'dx ';
            app.dxEditField = uieditfield(app.CreategridPanel, 'numeric');
            app.dxEditField.Value = 10;
            app.dyLabel = uilabel(app.CreategridPanel);
            app.dyLabel.Text = 'dy';
            app.dykmEditField = uieditfield(app.CreategridPanel, 'numeric');
            app.dykmEditField.Value = 5;
            app.zmaxkmEditFieldLabel = uilabel(app.CreategridPanel);
            app.zmaxkmEditFieldLabel.Text = 'zmax';
            app.zmaxkmEditField = uieditfield(app.CreategridPanel, 'numeric');
            app.zmaxkmEditField.Value = 200;
            app.dzLabel = uilabel(app.CreategridPanel);
            app.dzLabel.Text = 'dz';
            app.dzkmEditField = uieditfield(app.CreategridPanel, 'numeric');
            app.dzkmEditField.Value = 1;
            app.CreateButton = uibutton(app.CreategridPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.VelocityModelDropDownLabel = uilabel(app.CreategridPanel);
            app.VelocityModelDropDownLabel.Text = 'Velocity Model';
            app.VelocityModelDropDown = uidropdown(app.CreategridPanel);
            app.VelocityModelDropDown.Items = {'1D', '3D', 'Default'};
            app.VelocityModelDropDown.Value = 'Default';
            app.PlotparametersPanel = uipanel(app.UIFigure);
            app.PlotparametersPanel.Title = 'Plot parameters:';
            app.X1Label = uilabel(app.PlotparametersPanel);
            app.X1Label.Text = 'X1';
            app.X1EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.Y1Label = uilabel(app.PlotparametersPanel);
            app.Y1Label.Text = 'Y1';
            app.Y1EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.X2Label = uilabel(app.PlotparametersPanel);
            app.X2Label.Text = 'X2';
            app.X2EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.X2EditField.Value = 200;
            app.Y2Label = uilabel(app.PlotparametersPanel);
            app.Y2Label.Text = 'Y2';
            app.Y2EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.Y2EditField.Value = 130;
            app.PlotButton = uibutton(app.PlotparametersPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SaveButton = uibutton(app.PlotparametersPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GridPanel = uipanel(app.UIFigure);
            app.GridPanel.Title = 'Grid Panel';
            app.CCPcalculatePanel = uipanel(app.UIFigure);
            app.CCPcalculatePanel.Title = 'CCP calculate';
            app.SmoothingkmLabel = uilabel(app.CCPcalculatePanel);
            app.SmoothingkmLabel.Text = 'Smoothing';
            app.SmoothingEditField = uieditfield(app.CCPcalculatePanel, 'numeric');
            app.SmoothingEditField.Value = 15;
            app.RunButtonCCP = uibutton(app.CCPcalculatePanel, 'push');
            app.RunButtonCCP.ButtonPushedFcn = createCallbackFcn(app, @RunButtonCCPPushed, true);
            app.RunButtonCCP.Text = 'Run';
            app.CancelGUI = uibutton(app.UIFigure, 'push');
            app.CancelGUI.ButtonPushedFcn = createCallbackFcn(app, @CancelGUIPushed, true);
            app.CancelGUI.Text = 'Cancel';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCPStackingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end