classdef HkStackingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        HkParamPanel           matlab.ui.container.Panel
        dkEditField            matlab.ui.control.NumericEditField
        dkEditFieldLabel       matlab.ui.control.Label
        dhkmEditField          matlab.ui.control.NumericEditField
        dhkmEditFieldLabel     matlab.ui.control.Label
        kmaxEditField          matlab.ui.control.NumericEditField
        kmaxEditFieldLabel     matlab.ui.control.Label
        kminEditField          matlab.ui.control.NumericEditField
        kminEditFieldLabel     matlab.ui.control.Label
        HmaxkmEditField        matlab.ui.control.NumericEditField
        HmaxkmLabel            matlab.ui.control.Label
        HminkmEditField        matlab.ui.control.NumericEditField
        HminkmLabel            matlab.ui.control.Label
        ManualpickingButton    matlab.ui.control.Button
        OKButton               matlab.ui.control.Button
        QiuitButton            matlab.ui.control.Button
        HkPanelLabel           matlab.ui.control.Label
        SaveResultsButton      matlab.ui.control.Button
        StatusLabel1           matlab.ui.control.Label
        StatusLabel            matlab.ui.control.Label
        StopButton             matlab.ui.control.Button
        ResultsTable           matlab.ui.control.Table
        SelectStationDropDown  matlab.ui.control.DropDown
        RunButton              matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Params      
        HKresults 
        mainApp  
        DataStruct 
        plotData
        StopFlag = false; 
        PauseFlag = false;
    end
    

    methods (Access = public)
        function loadConfigData(app,defaultParam)
            app.HminkmEditField.Value = defaultParam.hmin;
            app.HmaxkmEditField.Value = defaultParam.hmax;
            
            app.kminEditField.Value = defaultParam.kmin;
            app.kmaxEditField.Value = defaultParam.kmax;
            
            updateStationDropdown(app)
            
        end
    end

   
    methods (Access = private)
        
        function updateStationDropdown(app)
            if isempty(app.DataStruct)
                app.SelectStationDropDown.Items = {'(no stations)'};
                app.SelectStationDropDown.Value = '(no stations)';
            else
                gather = app.DataStruct;
                stationList = getStations(gather);

%                 stationList = getStation(app.DataStruct);
                stationNames = {stationList.sta};
                items = [{'None', 'All'}, stationNames];
                app.SelectStationDropDown.Items = items;
                app.SelectStationDropDown.Value = 'None';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'HkStacking App';
            if isempty(app.DataStruct)
%                 disp('DataStruct is empty123.');
            else
                disp('DataStruct received:');
                disp(app.DataStruct);
            end

            updateStationDropdown(app)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            global Hall
            global kappa


            Hmin = app.HminkmEditField.Value; 
            Hmax = app.HmaxkmEditField.Value; 
            dh = app.dhkmEditField.Value;

            kmin = app.kminEditField.Value;
            kmax = app.kmaxEditField.Value;
            dk = app.dkEditField.Value;
            
            if isnan(Hmin) || isnan(Hmax)
                uialert(app.UIFigure, 'Input Error', 'Error');
                return;
            end

            Hall = Hmin:dh:Hmax;
            kappa = kmin:dk:kmax;

            app.HKresults = [];  
            app.plotData = []; 
            app.ResultsTable.Data = {}; 
            app.StopFlag = false;  
            app.StatusLabel.Text = 'Computing...';  % 

            if isempty(app.DataStruct)
                uialert(app.UIFigure, 'Load data first', 'Error');
                return;
            end

            selStation = app.SelectStationDropDown.Value;
            if strcmpi(selStation, 'All')
                try
                    app.StatusLabel.Text = 'Computing all stations!';

                    [app.HKresults, app.plotData] = HKstacking(app.DataStruct, app, Hall,kappa);
                catch ME
                    uialert(app.UIFigure, ['HKstacking error：' ME.message], 'error');
                    return;
                end

                if isempty(app.HKresults)
                    uialert(app.UIFigure, ['HKstacking error：' ME.message], 'error');
                    return;
                end
            else
                gather = getCommonStationGather(app.DataStruct, selStation);
                if isempty(gather)
                    uialert(app.UIFigure, ['Cannot get ' selStation ' data'], 'Error');
                    return;
                end
                % try
%                     HKres = HKstacking(gather);
                    app.StatusLabel.Text = ['Computing station ', selStation];
                    [HKres, app.plotData] = HKstacking(gather, app, Hall,kappa);

                % catch ME
                %     uialert(app.UIFigure, ['HKstacking Error' ME.message], 'Error');
                %     return;
                % end
                app.HKresults = HKres;

            end
            if ~isempty(app.HKresults)
                nResults = numel(app.HKresults);
                data = cell(nResults, 7);  
                for i = 1:nResults
                    data{i, 1} = app.HKresults(i).Sta;         
                    data{i, 2} = sprintf('%.2f', app.HKresults(i).Lon); 
                    data{i, 3} = sprintf('%.2f', app.HKresults(i).Lat);  
                    data{i, 4} = sprintf('%.2f', app.HKresults(i).H);     
                    data{i, 5} = sprintf('%.2f', app.HKresults(i).Hstd);  
                    data{i, 6} = sprintf('%.2f', app.HKresults(i).k); 
                    data{i, 7} = sprintf('%.2f', app.HKresults(i).kstd); 
                end
                app.ResultsTable.ColumnName = {'Sta', 'Lon', 'Lat', 'H (km)', 'Hstd (km)', 'k', 'kstd'};

                app.ResultsTable.Data = data;
                leftAlignStyle = uistyle('HorizontalAlignment', 'left');
                addStyle(app.ResultsTable, leftAlignStyle);
            end

            uialert(app.UIFigure, 'Click the table to plot', 'Note');
            app.StatusLabel.Text = 'HK done!';

        end

        % Cell selection callback: ResultsTable
        function ResultsTableCellSelection(app, event)
            if ~isempty(event.Indices)
                
                row = event.Indices(1); 

                app.StatusLabel.Text = 'Plotting';  
                drawnow;
                
                Hall = app.plotData(row).Hall;
                kappa = app.plotData(row).kappa;
                Cn = app.plotData(row).Cn;
                besth = app.plotData(row).besth;
                bestk = app.plotData(row).bestk;
                hstd = app.plotData(row).hstd;
                kstd = app.plotData(row).kstd;
                sta = app.HKresults(row).Sta; 

                cla(app.UIAxes_2);  
                hold(app.UIAxes_2, 'on');  

                imagesc(app.UIAxes_2, Hall, kappa, Cn');  
                scatter(app.UIAxes_2, besth, bestk, 100, 'red', '*');

                text(app.UIAxes_2, 0.98, 0.98, ...
                    ['Depth=', num2str(besth, '%.2f'), setstr(177), num2str(hstd, '%.2f'), ' km'], ...
                    'Units', 'normalized', 'HorizontalAlignment', 'right', ...
                    'VerticalAlignment', 'top', 'Color', 'k', 'FontSize', 8, 'BackgroundColor', 'w');
                
                text(app.UIAxes_2, 0.98, 0.90, ...
                    ['Vp/Vs=', num2str(bestk, '%.2f'), setstr(177), num2str(kstd, '%.2f')], ...
                    'Units', 'normalized', 'HorizontalAlignment', 'right', ...
                    'VerticalAlignment', 'top', 'Color', 'k', 'FontSize', 8, 'BackgroundColor', 'w');

                axis(app.UIAxes_2, 'xy');
                xlabel(app.UIAxes_2, 'Depth (km)', 'FontSize', 18);
                ylabel(app.UIAxes_2, 'Vp/Vs', 'FontSize', 18);
                title(app.UIAxes_2, [sta, ' HK stacking'], 'FontSize', 16);
                colormap(app.UIAxes_2, 'sky');  
                colorbar(app.UIAxes_2); box(app.UIAxes_2,"on")
                axis(app.UIAxes_2, 'tight');
                set(app.UIAxes_2, 'LooseInset', [0 0 0 0],'FontSize',18,'LineWidth',1.5);

                hold(app.UIAxes_2, 'off'); 

                t = app.plotData(row).t;
                seis = app.plotData(row).seis;
                dist = app.plotData(row).dist;
                rayp = app.plotData(row).rayp;
                Tdelay_Ps = app.plotData(row).Tdelay_Ps;
                Tdelay_PpPms = app.plotData(row).Tdelay_PpPms;
                Tdelay_PpSms = app.plotData(row).Tdelay_PpSms;
                vp_ave = app.plotData(row).vp_ave;
                indexh = app.plotData(row).indexh;
                indexk = app.plotData(row).indexk;
                
                cla(app.UIAxes);
%                 axes(app.UIAxes);
                hold(app.UIAxes, 'on');
                
                for id = length(dist):-1:1
                    x = seis(:, id) * 30;
                    shift = dist(id);
                    plot(app.UIAxes, t, x + shift, 'k', 'LineWidth', 1);

                    thre = 0.01;
                    upper = x;
                    upper(upper <= thre) = 0;
                    upper(1) = 0; upper(end) = 0;
                    lower = zeros(length(x),1);
                    
                    jbfill_1(t(:), upper + shift, lower + shift, [149 57 132]/155, [0.2 0.2 0.2], 0, 0.5, app.UIAxes);

                    upper = zeros(length(x),1);
                    lower = x;
                    lower(lower >= -thre) = 0;
                    lower(1) = 0; lower(end) = 0;
                    jbfill_1(t(:), upper + shift, lower + shift, [178 178 178]/255, [0.2 0.2 0.2], 0, 0.5, app.UIAxes);
                end
                xlim(app.UIAxes, [-5, 35]);
                ylim(app.UIAxes, [20, 100]);

                for id = length(dist):-1:1
                    shift = dist(id);
                    % p = rayp(id) / 6371; % s/km
                    % t0p1s = besth * (sqrt((bestk/vp_ave)^2 - p^2) - sqrt((1/vp_ave)^2 - p^2));
                    % t2p1s = besth * (sqrt((bestk/vp_ave)^2 - p^2) + sqrt((1/vp_ave)^2 - p^2));
                    % t1p2s = 2 * besth * sqrt((bestk/vp_ave)^2 - p^2);
                    % scatter(app.UIAxes, 0, shift, 20, 'blue', 'filled');
                    % scatter(app.UIAxes, t0p1s, shift, 20, 'black', 'filled');
                    % scatter(app.UIAxes, t2p1s, shift, 20, 'black', 'filled');
                    % scatter(app.UIAxes, t1p2s, shift, 20, 'black', 'filled');

                    % crust 1.0 model
                    t0p1s_delay = Tdelay_Ps(indexh, id, indexk);
                    t2p1s_delay = Tdelay_PpPms(indexh, id, indexk);
                    t1p2s_delay = Tdelay_PpSms(indexh, id, indexk);
                    scatter(app.UIAxes, 0, shift, 20, 'blue', 'filled');
                    scatter(app.UIAxes, t0p1s_delay, shift, 20, 'blue', 'filled');
                    scatter(app.UIAxes, t2p1s_delay, shift, 20, 'blue', 'filled');
                    scatter(app.UIAxes, t1p2s_delay, shift, 20, 'blue', 'filled');
                end
                title(app.UIAxes, [sta, ' waveform'], 'FontSize', 18);
                xlabel(app.UIAxes, 'Time (sec)');
                ylabel(app.UIAxes, 'Distance (deg)');
                set(app.UIAxes, 'FontSize', 18,'linewidth',1.5);
                box(app.UIAxes,'on')
                hold(app.UIAxes, 'off');

            end
            app.StatusLabel.Text = 'Hk plots done'; 
           
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopFlag = true;
            app.StatusLabel.Text = 'Compute stop'; 
            uialert(app.UIFigure, 'Compute stop', 'Note');
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)

            if isempty(app.HKresults)
                uialert(app.UIFigure, 'No data to save', 'Error');
                return;
            end

            [file, path] = uiputfile('*.txt', 'Save');
            if isequal(file, 0)
                return;
            end

            fullFileName = fullfile(path, file);

            fid = fopen(fullFileName, 'w');
            if fid == -1
                uialert(app.UIFigure, 'Cannot open the file', 'Error');
                return;
            end

            fprintf(fid, 'Sta\tLon\tLat\tH (km)\tHstd (km)\tk\tkstd\n');

            for i = 1:numel(app.HKresults)
                fprintf(fid, '%s\t%.2f\t%.2f\t%.2f\t%.2f\t%.2f\t%.2f\n', ...
                    app.HKresults(i).Sta, ...
                    app.HKresults(i).Lon, ...
                    app.HKresults(i).Lat, ...
                    app.HKresults(i).H, ...
                    app.HKresults(i).Hstd, ...
                    app.HKresults(i).k, ...
                    app.HKresults(i).kstd);
            end
            fclose(fid);
            uialert(app.UIFigure, ['Results are saved to: ', fullFileName], 'Success');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.mainApp.hkStackingStatus = 'OK';
            delete(app.UIFigure);
        end

        % Button pushed function: QiuitButton
        function QiuitButtonPushed(app, event)
            answer = questdlg('Do you want to quit?', '', 'YES', 'NO', 'NO');
            switch answer
                case 'YES'
                    delete(app.UIFigure); 
                case 'NO'
                    return; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'HK stacking')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'RF waveform')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectStationDropDown = uidropdown(app.UIFigure);
            app.SelectStationDropDown.Items = {'Select Station'};
            app.SelectStationDropDown.Value = 'Select Station';
            app.ResultsTable = uitable(app.UIFigure);
            app.ResultsTable.ColumnName = {'Sta'; 'Lon'; 'Lat'; 'H'; 'Hstd'; 'k'; 'kstd'};
            app.ResultsTable.RowName = {};
            app.ResultsTable.CellSelectionCallback = createCallbackFcn(app, @ResultsTableCellSelection, true);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = '';
            app.StatusLabel1 = uilabel(app.UIFigure);
            app.StatusLabel1.Text = 'Status：';
            app.SaveResultsButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.HkPanelLabel = uilabel(app.UIFigure);
            app.HkPanelLabel.Text = 'H-k Panel';
            app.QiuitButton = uibutton(app.UIFigure, 'push');
            app.QiuitButton.ButtonPushedFcn = createCallbackFcn(app, @QiuitButtonPushed, true);
            app.QiuitButton.Text = 'Qiuit';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ManualpickingButton = uibutton(app.UIFigure, 'push');
            app.ManualpickingButton.Text = 'Manual picking';
            app.HkParamPanel = uipanel(app.UIFigure);
            app.HkParamPanel.Title = 'Hk Param';
            app.HminkmLabel = uilabel(app.HkParamPanel);
            app.HminkmLabel.Text = 'Hmin (km)';
            app.HminkmEditField = uieditfield(app.HkParamPanel, 'numeric');
            app.HminkmEditField.Value = 30;
            app.HmaxkmLabel = uilabel(app.HkParamPanel);
            app.HmaxkmLabel.Text = 'Hmax (km)';
            app.HmaxkmEditField = uieditfield(app.HkParamPanel, 'numeric');
            app.HmaxkmEditField.Value = 70;
            app.kminEditFieldLabel = uilabel(app.HkParamPanel);
            app.kminEditFieldLabel.Text = 'k min';
            app.kminEditField = uieditfield(app.HkParamPanel, 'numeric');
            app.kminEditField.Value = 1.6;
            app.kmaxEditFieldLabel = uilabel(app.HkParamPanel);
            app.kmaxEditFieldLabel.Text = 'k max';
            app.kmaxEditField = uieditfield(app.HkParamPanel, 'numeric');
            app.kmaxEditField.Value = 2;
            app.dhkmEditFieldLabel = uilabel(app.HkParamPanel);
            app.dhkmEditFieldLabel.Text = 'dh (km)';
            app.dhkmEditField = uieditfield(app.HkParamPanel, 'numeric');
            app.dhkmEditField.Value = 0.1;
            app.dkEditFieldLabel = uilabel(app.HkParamPanel);
            app.dkEditFieldLabel.Text = 'dk';
            app.dkEditField = uieditfield(app.HkParamPanel, 'numeric');
            app.dkEditField.Value = 0.01;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HkStackingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end