classdef Migration3DApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImportMenu                  matlab.ui.container.Menu
        SyntheticDataMenu           matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        GridPanel                   matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        SSAParTab                   matlab.ui.container.Tab
        AlphaEditField              matlab.ui.control.NumericEditField
        AlphaEditFieldLabel         matlab.ui.control.Label
        RankEditField               matlab.ui.control.NumericEditField
        RankEditFieldLabel          matlab.ui.control.Label
        Is_SSADropDown              matlab.ui.control.DropDown
        Is_SSALabel                 matlab.ui.control.Label
        LSMParTab                   matlab.ui.container.Tab
        MuEditField                 matlab.ui.control.NumericEditField
        MuEditFieldLabel            matlab.ui.control.Label
        IterationEditField          matlab.ui.control.NumericEditField
        IterationEditField_2Label   matlab.ui.control.Label
        RunButtonLSM                matlab.ui.control.Button
        SelectEventsDropDown        matlab.ui.control.DropDown
        PlotparametersPanel         matlab.ui.container.Panel
        SaveButton                  matlab.ui.control.Button
        PlotButton                  matlab.ui.control.Button
        Y2EditField                 matlab.ui.control.NumericEditField
        Y2EditFieldLabel            matlab.ui.control.Label
        X2EditField                 matlab.ui.control.NumericEditField
        X2EditFieldLabel            matlab.ui.control.Label
        Y1EditField                 matlab.ui.control.NumericEditField
        Y1EditFieldLabel            matlab.ui.control.Label
        X1EditField                 matlab.ui.control.NumericEditField
        X1EditFieldLabel            matlab.ui.control.Label
        CreateGridPanel             matlab.ui.container.Panel
        dxkmEditField               matlab.ui.control.NumericEditField
        dxLabel                     matlab.ui.control.Label
        dzkmEditField               matlab.ui.control.NumericEditField
        dzkmEditFieldLabel          matlab.ui.control.Label
        dykmEditField               matlab.ui.control.NumericEditField
        dykmEditFieldLabel          matlab.ui.control.Label
        VelocityModelDropDown       matlab.ui.control.DropDown
        VelocityModelDropDownLabel  matlab.ui.control.Label
        CreateButton                matlab.ui.control.Button
        QuitButton                  matlab.ui.control.Button
        OKButton                    matlab.ui.control.Button
        Migration3DPanelLabel       matlab.ui.control.Label
        UIAxes_lsm                  matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    %% user define properties
    properties (Access = public)
        mainApp
        MigrationParam
        DataStruct
        gridStruct
        migResults
        
    end
    
    properties (Access = private)
        selectedEvid 
        cmap
    end
    %% user define methods
    methods (Access = public)
        function loadConfigData(app, defaultParam)
            app.MigrationParam = defaultParam;  

            if defaultParam.is_ssa == 1
                app.Is_SSADropDown.Value='YES';
            elseif defaultParam.is_ssa == 0
                app.Is_SSADropDown.Value='NO';
            end

            app.RankEditField.Value = defaultParam.rank_p;
            app.AlphaEditField.Value = defaultParam.alpha;

            updateEventDropdown(app)
        end

    end

    methods (Access = private)
        function updateEventDropdown(app)

            if isempty(app.DataStruct)
                app.SelectEventsDropDown.Items = {'No events'};
                app.SelectEventsDropDown.Value = 'No events';
                return;
            end

            eList = getEvents(app.DataStruct);
            eIDs  = {eList.evid};
            items = [{'Select events','All'}, eIDs];

            app.SelectEventsDropDown.Items = items;
            app.SelectEventsDropDown.Value = items{1}; 
        end

        function plotGridInApp(app, gridStruct,isplotLine)

            delete(allchild(app.GridPanel));
            
            stationX = gridStruct.rxInOriginalCoord(:, 1);
            stationY = gridStruct.ryInOriginalCoord(:, 2);
            
            XX = gridStruct.XInOriginalCoord;
            YY = gridStruct.YInOriginalCoord;
            
            gax = axes('Parent', app.GridPanel, ...
                'Units', 'normalized', ...
                'Position', [0.05 0.12 0.95 0.80]);
            hold(gax, 'on');
            
            scatter(gax, stationX, stationY, 40, 'r^', 'filled', ...
                'LineWidth', 1.5, ...
                'MarkerEdgeColor', 'r', ...
                'DisplayName', 'Station');
            
            scatter(gax, XX, YY, 80, [0.5 0.5 0.5], '.', ...
                'filled', 'MarkerEdgeColor', 'k', ...
                'DisplayName', 'Grid');
            
            xlabel(gax, 'Easting (km)', 'FontSize', 12, 'FontWeight', 'bold');
            ylabel(gax, 'Northing (km)', 'FontSize', 12, 'FontWeight', 'bold');
            title(gax, 'Station geometry', 'FontSize', 14, 'FontWeight', 'bold');

            box(gax,"on");
            grid(gax, 'on');
            grid(gax, 'minor');
            
            axis(gax, 'equal');

            xMargin = 0.1 * (max(XX(:)) - min(XX(:))); 
            yMargin = 0.1 * (max(YY(:)) - min(YY(:))); 
            xlim(gax, [min(XX(:)) - xMargin, max(XX(:)) + xMargin]);
            ylim(gax, [min(YY(:)) - yMargin, max(YY(:)) + yMargin]);
            
            set(gax, 'FontSize', 10);
            set(gax, 'Color', 'w');
            set(app.GridPanel, 'BackgroundColor', 'w');

            if isplotLine
                coord1 = [app.X1EditField.Value,app.Y1EditField.Value];
                coord2 = [app.X2EditField.Value,app.Y2EditField.Value];
    
                plot(gax,[coord1(1),coord2(1)],[coord1(2),coord2(2)],'b-o','LineWidth',2)
                % plot(gax,coord1, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r'); 
                % plot(gax,coord1, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');
    
                text(gax,coord1(1),coord1(2),'A','FontSize',20,'HorizontalAlignment','right')
                text(gax,coord2(1),coord2(2),'B','FontSize',20,'HorizontalAlignment','left')

            end

            grid(gax, 'on');
            hold(gax, 'off');
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Migration3D App';
            app.cmap = load(fullfile(pwd,'/visualization/colormap/roma.mat'));
            updateEventDropdown(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.mainApp.migration3DStatus = 'OK';
  
            % write_MigResult(['./results', '/mig3D.mat'], app.migResults);

            delete(app.UIFigure);
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            if isempty(app.DataStruct)
                uialert(app.UIFigure, 'No data loaded!', 'Error','Icon','error');
                return;
            end

            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','Meshgrid', ...
                    'Message','Generate grid and velocity model...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');
            % create grid
            dx = app.dxkmEditField.Value;
            dy = app.dykmEditField.Value;
            dz = app.dzkmEditField.Value;
             % velocity model
            ModelType = app.VelocityModelDropDown.Value;
           
            app.gridStruct = createGrid(app.DataStruct, dx, dy, dz);
           
            if strcmp(ModelType,'Default')
                ModelType = '3D';
            end

            npts = 5;
            app.gridStruct = getVelocityModel(ModelType,app.gridStruct,npts);

            plotGridInApp(app,app.gridStruct,0);

            close(msg);
        end

        % Value changed function: SelectEventsDropDown
        function SelectEventsDropDownValueChanged(app, event)

            selectedID = app.SelectEventsDropDown.Value;
            if strcmp(selectedID,'All')

                eList = getEvents(app.DataStruct);  
                app.selectedEvid = {eList.evid}; 
            else

                app.selectedEvid = {selectedID};
            end
        end

        % Button pushed function: RunButtonLSM
        function RunButtonLSMPushed(app, event)
            shots = app.selectedEvid;
            if isempty(shots)
                uialert(app.UIFigure, 'Select at least one earthquake!', 'Note');
                return;
            end
            if ~isfield(app.gridStruct,'VP')     
                uialert(app.UIFigure, 'Create grid first!', 'Note');
                return;
            end
            
            MigParam = app.MigrationParam;
            MigParam.paramMig = setMigParam3D(app.gridStruct);
            MigParam.paramMig.itermax = app.IterationEditField.Value;
            MigParam.paramMig.mu = app.MuEditField.Value;
            
            is_ssa = app.Is_SSADropDown.Value;
            if strcmp(is_ssa,'Default') | strcmp(is_ssa,'NO')
                is_ssa = 0;
            elseif strcmp(is_ssa,'YES')
                is_ssa = 1;

                RankReductionParam.nx = app.gridStruct.nx;
                RankReductionParam.ny = app.gridStruct.ny;
                RankReductionParam.ox = min(app.gridStruct.x);
                RankReductionParam.oy = min(app.gridStruct.y);
                RankReductionParam.mx = max(app.gridStruct.x);
                RankReductionParam.my = max(app.gridStruct.y);
                RankReductionParam.rank = app.RankEditField.Value;

            end
            MigParam.paramMig.isReconRFs = is_ssa;

            nshots = length(shots);
            dout = struct();
            for iEvent = 1:nshots
                msg = uiprogressdlg(app.UIFigure, ...
                    'Title','LSM', ...
                    'Message',['LSM begins ',num2str(iEvent),' of ',num2str(nshots),' ...'], ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');

                evid = shots{iEvent};
                
                gather = getCommonEventGather(app.DataStruct, evid);

                if is_ssa
                    [gather, d1_otg] = rankReduction_new(gather, app.gridStruct, RankReductionParam);
                    MigParam.paramMig.dotg = d1_otg;
                end
                % Perform 3D least-squares migration
                % This method provides improved resolution compared to standard migration
                mig = leastSquaresMig3D(gather, app.gridStruct, MigParam);
                dout(iEvent).images = mig;

            end
            app.migResults = dout;
            close(msg)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %% plot results
            plotGridInApp(app,app.gridStruct,1);
            %% 
            if isempty(app.migResults)
                uialert(app.UIFigure, 'no data!', 'Note');
                return;

            end
            x1 = app.X1EditField.Value;
            x2 = app.X2EditField.Value;
            y1 = app.Y1EditField.Value;
            y2 = app.Y2EditField.Value;

            dout = app.migResults;       % all events
            
            V1 = zeros(size(dout(1).images.mig));
            V2 = V1;
            for n=1:length(dout)
                V1 = V1 + dout(n).images.mig;
                V2 = V2 + dout(n).images.migls;
            end
            V1 = V1/length(dout);
            V1 = permute(V1,[3,2,1]);

            V2 = V2/length(dout);
            V2 = permute(V2,[3,2,1]);


            X = dout(1).images.X;
            Y = dout(1).images.Y;
            Z = dout(1).images.Z;
            [~,~,nz] = size(X);

            % shotsID = app.selectedEvid;
            Fi = scatteredInterpolant(X(:),Y(:),Z(:),V2(:));

            ninterp = 50;
            xi = linspace(x1,x2,ninterp);
            yi = linspace(y1,y2,ninterp);
            depth = squeeze(Z(1,1,:));

            xdist = linspace(0,sqrt((x1-x2)^2 + (y1-y2)^2),ninterp);

            xpro = repmat(xi,nz,1);
            ypro = repmat(yi,nz,1);
            zpro = repmat(depth,1,ninterp);

            Vpro = Fi(xpro,ypro,zpro);


            cla(app.UIAxes_lsm,"reset");  
            % axes(app.UIAxes_lsm);
            % hold(app.UIAxes_lsm, 'on'); 
            imagesc(app.UIAxes_lsm, xdist, depth, Vpro); 
            
            axis(app.UIAxes_lsm, 'equal');
            title(app.UIAxes_lsm,'LSM image')
            xlabel(app.UIAxes_lsm, 'Distance (km)');
            ylabel(app.UIAxes_lsm, 'Depth (km)');
            xlim(app.UIAxes_lsm, [0, max(xdist)]);
            ylim(app.UIAxes_lsm, [0, 100]);
            text(app.UIAxes_lsm,0,-5,'A','FontSize',14)
            text(app.UIAxes_lsm,max(xdist)-5,-5,'B','FontSize',14)
                        
            cmax = rms(Vpro(:)); 
            clim(app.UIAxes_lsm, [-3*cmax, 3*cmax]);
            % caxis(app.CCPImageMap, [-1 1]);
%             colormap(app.CCPImageMap, 'jet'); 
            colormap(app.UIAxes_lsm,flipud(app.cmap.roma));
            colorbar(app.UIAxes_lsm)
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            answer = questdlg('Do you want to quit?', '', 'YES', 'NO', 'NO');
            switch answer
                case 'YES'
                    delete(app.UIFigure); 
                case 'NO'
                    return; 
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % save the figure
            if isempty(app.migResults)
                uialert(app.UIFigure, 'No results', 'Error', 'Icon','error');
                return;
            end
            % export_fig('./results/',app.UIAxes_lsm)
  
            uialert(app.UIFigure,'Saved the results','Sucess');
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportMenu = uimenu(app.UIFigure);
            app.ImportMenu.Text = 'Import';
            app.SyntheticDataMenu = uimenu(app.ImportMenu);
            app.SyntheticDataMenu.Text = 'Synthetic Data';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UIAxes_lsm = uiaxes(app.UIFigure);
            title(app.UIAxes_lsm, 'LSM image')
            xlabel(app.UIAxes_lsm, 'Distance (km)')
            ylabel(app.UIAxes_lsm, 'Depth (km)')
            zlabel(app.UIAxes_lsm, 'Z')
            app.Migration3DPanelLabel = uilabel(app.UIFigure);
            app.Migration3DPanelLabel.Text = '3D Migration Panel';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.CreateGridPanel = uipanel(app.UIFigure);
            app.CreateGridPanel.Title = 'Create Grid';
            app.CreateButton = uibutton(app.CreateGridPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.VelocityModelDropDownLabel = uilabel(app.CreateGridPanel);
            app.VelocityModelDropDownLabel.Text = 'Velocity Model:';
            app.VelocityModelDropDown = uidropdown(app.CreateGridPanel);
            app.VelocityModelDropDown.Items = {'1D', '2D', '3D', 'Default'};
            app.VelocityModelDropDown.Value = 'Default';
            app.dykmEditFieldLabel = uilabel(app.CreateGridPanel);
            app.dykmEditFieldLabel.Text = 'dy (km):';
            app.dykmEditField = uieditfield(app.CreateGridPanel, 'numeric');
            app.dykmEditField.Value = 5;
            app.dzkmEditFieldLabel = uilabel(app.CreateGridPanel);
            app.dzkmEditFieldLabel.Text = 'dz (km):';
            app.dzkmEditField = uieditfield(app.CreateGridPanel, 'numeric');
            app.dzkmEditField.Value = 1;
            app.dxLabel = uilabel(app.CreateGridPanel);
            app.dxLabel.Text = 'dx (km):';
            app.dxkmEditField = uieditfield(app.CreateGridPanel, 'numeric');
            app.dxkmEditField.Value = 5;
            app.PlotparametersPanel = uipanel(app.UIFigure);
            app.PlotparametersPanel.Title = 'Plot parameters:';
            app.X1EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.X2EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.X2EditField.Value = 200;
            app.Y2EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.Y2EditField.Value = 130;
            app.PlotButton = uibutton(app.PlotparametersPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SaveButton = uibutton(app.PlotparametersPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SelectEventsDropDown = uidropdown(app.UIFigure);
            app.SelectEventsDropDown.Items = {'Select Events'};
            app.SelectEventsDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectEventsDropDownValueChanged, true);
            app.SelectEventsDropDown.Value = 'Select Events';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SSAParTab = uitab(app.TabGroup);
            app.SSAParTab.Title = 'SSA Par';
            app.Is_SSALabel = uilabel(app.SSAParTab);
            app.Is_SSALabel.Text = 'Is_SSA:';
            app.Is_SSADropDown = uidropdown(app.SSAParTab);
            app.Is_SSADropDown.Items = {'Default', 'YES', 'NO'};
            app.Is_SSADropDown.Value = 'Default';
            app.RankEditFieldLabel = uilabel(app.SSAParTab);
            app.RankEditFieldLabel.Text = 'Rank:';
            app.RankEditField = uieditfield(app.SSAParTab, 'numeric');
            app.RankEditField.Value = 8;
            app.AlphaEditFieldLabel = uilabel(app.SSAParTab);
            app.AlphaEditFieldLabel.Text = 'Alpha:';
            app.AlphaEditField = uieditfield(app.SSAParTab, 'numeric');
            app.AlphaEditField.Value = 0.9;
            app.LSMParTab = uitab(app.TabGroup);
            app.LSMParTab.Title = 'LSM Par';
            app.RunButtonLSM = uibutton(app.LSMParTab, 'push');
            app.RunButtonLSM.ButtonPushedFcn = createCallbackFcn(app, @RunButtonLSMPushed, true);
            app.RunButtonLSM.Text = 'Run';
            app.IterationEditField_2Label = uilabel(app.LSMParTab);
            app.IterationEditField_2Label.Text = 'Iteration:';
            app.IterationEditField = uieditfield(app.LSMParTab, 'numeric');
            app.IterationEditField.Value = 20;
            app.MuEditFieldLabel = uilabel(app.LSMParTab);
            app.MuEditFieldLabel.Text = 'Mu:';
            app.MuEditField = uieditfield(app.LSMParTab, 'numeric');
            app.MuEditField.Value = 0.1;
            app.GridPanel = uipanel(app.UIFigure);
            app.GridPanel.Title = 'Grid Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Migration3DApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end